classdef EEGmotifs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGmotifsUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        SelectLoadingButton             matlab.ui.control.Button
        SelectSavingButton              matlab.ui.control.Button
        SelectsourcefolderLabel         matlab.ui.control.Label
        SelectdestinationfolderLabel    matlab.ui.control.Label
        LaunchButton                    matlab.ui.control.Button
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        AnalysisTechniqueDropDownLabel  matlab.ui.control.Label
        AnalysisTechniqueDropDown       matlab.ui.control.DropDown
        NumberofmotifsDropDownLabel     matlab.ui.control.Label
        NumberMotifsDropDown            matlab.ui.control.DropDown
        FeedbackArea                    matlab.ui.control.TextArea
        SourceLamp                      matlab.ui.control.Lamp
        DestinationLamp                 matlab.ui.control.Lamp
        TypeofGraphDropDownLabel        matlab.ui.control.Label
        TypeofGraphDropDown             matlab.ui.control.DropDown
    end


    properties (Access = private)
        % app_data structure
        parameters;
        information;
    end

    methods (Access = private)
        
        % Will update the content of the GUI 
        function update_gui(app)
            
            %% Update the Analysis Technique selection box
            if(app.parameters.general.is_dpli)
               app.AnalysisTechniqueDropDown.Value = "dPLI";
            elseif(app.parameters.general.is_nste)
               app.AnalysisTechniqueDropDown.Value = "NSTE";
            end
            
            % Update the number of motifs
            if(app.parameters.general.number_motifs == 3)
               app.NumberMotifsDropDown.Value = "3";
            elseif(app.parameters.general.number_motifs == 4)
               app.NumberMotifsDropDown.Value = "4";
            end
            
            % Update Type of Graph
            if(app.parameters.general.is_binary)
               app.TypeofGraphDropDown.Value = "Binary"; 
               app.ThresholdEditField.Enable = 1;
            elseif(app.parameters.general.is_weighted)
               app.TypeofGraphDropDown.Value = "Weighted";
               app.ThresholdEditField.Enable = 0; 
            end
            
            % Update the Thresholds
            app.ThresholdEditField.Value = app.parameters.general.threshold;
            
        end
    
        
        % Will save the content of the GUI Parameters
        function save_gui(app)
            
            % Save the data
            app.parameters.general.is_dpli = is_selected(app,"analysis_technique","dPLI");
            app.parameters.general.is_nste = is_selected(app,"analysis_technique","NSTE");
            app.parameters.general.number_motifs = get_number_motifs(app);
            app.parameters.general.is_weighted = is_selected(app,"graph_type","Weighted");
            app.parameters.general.is_binary = is_selected(app,"graph_type","Binary");
            app.parameters.general.threshold = get_threshold(app);
            
             %Write to app_data
            parameters = app.parameters;
            information = app.information;
            save(strcat(app.information.base_directory,'/data/app_data'),'parameters','-append');
            save(strcat(app.information.base_directory,'/data/app_data'),'information','-append');
        
        end
        
        function write(app,message)
            app.FeedbackArea.Value = message;
        end
    
        function refresh(app)
            % Fonctionality to have app on top of all windows
            app.EEGmotifsUIFigure.Visible = 'off';
            app.EEGmotifsUIFigure.Visible = 'on';
            
            % Light up or down the Loading Lamp
            if(~strcmp(app.information.loading_path,""))
                app.SourceLamp.Color = [0,1,0];
            else
                app.SourceLamp.Color = [1,0,0];
            end
            
            % Light up or down the Saving Lamp
            if(~strcmp(app.information.saving_path,""))
                app.DestinationLamp.Color = [0,1,0];
            else
                app.DestinationLamp.Color = [1,0,0];
            end
            
            if(is_ready(app))
                app.LaunchButton.Enable = 1;
                app.LaunchButton.BackgroundColor = [0,1,0];
                write(app,"EEGMotifs is ready!");
            else
                app.LaunchButton.Enable = 0;
                app.LaunchButton.BackgroundColor = [1,0,0];
            end
            
            % Update the GUI
            update_gui(app);
        end
    
        function clean_up(app)
            %% Clean up folders if doesn't exist in this computer
            % Variables Setup
            loading_folder = app.information.loading_path;
            saving_folder = app.information.saving_path;
            
            % Reseting loading_path
            if(~exist(loading_folder, 'dir'))
                app.information.loading_path = "";
            end
            
            % Reseting saving_path
            if(~exist(saving_folder, 'dir'))
                app.information.saving_path = "";
            end
           
        end
    
        function clear(app)
           app.information.loading_path = "";
           app.information.saving_path = "";
           write(app,"Cleared!");
        end
    
        %% Helper function (for clarity)
        function [selected] = is_selected(app,type,option)
            selected = 0;
            value = "";
            if(strcmp(type,"analysis_technique"))
               value = app.AnalysisTechniqueDropDown.Value;
            elseif(strcmp(type,"graph_type"))
                value = app.TypeofGraphDropDown.Value;
            end
            selected = strcmp(value,option);
        end
    
        function [number_motifs] = get_number_motifs(app)
           number_motifs = str2num(app.NumberMotifsDropDown.Value); 
        end
    
        function [threshold] = get_threshold(app)
            threshold = app.ThresholdEditField.Value;
        end
    
        function [ready] = is_ready(app)
           ready_source = isequal(app.SourceLamp.Color,[0,1,0]);
           ready_destination = isequal(app.DestinationLamp.Color,[0,1,0]);
           ready = ready_source && ready_destination;
        end
    
        function add_paths(app)
           % We add all the folder required for this application
           base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
           lib_path = strcat(base_directory,filesep,'lib');
           data_path = strcat(base_directory,filesep,'data');
           addpath(genpath(lib_path));
           addpath(genpath(data_path));
        end
    
        function remove_paths(app)
            % We remove all the folder required for this application
           base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
           lib_path = strcat(base_directory,filesep,'lib');
           data_path = strcat(base_directory,filesep,'data');
           rmpath(genpath(lib_path));
           rmpath(genpath(data_path));
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Here will need to load from app_data
            % Setup
            add_paths(app);
            base_directory = erase(mfilename('fullpath'),strcat(filesep,mfilename));
            
            % Setting up Variables
            app_data = load(strcat(base_directory,strcat(filesep,'data',filesep,'app_data')));
            app.information = app_data.information;    
            app.parameters = app_data.parameters;
            
            % Updating computer specific variables
            app.information.base_directory = base_directory;
            
            % Cleanup
            clean_up(app)
            
            % Refresh the application
            refresh(app)
            
            % Write to feedback
            write(app,"Welcome to EEGMotifs!");
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            save_gui(app);
            app.delete();
        end

        % Button pushed function: SelectLoadingButton
        function SelectLoadingButtonPushed(app, event)
            % Selecting a path
            selected_path = uigetdir;
            
            % Fetching variables:
            current_path = app.information.loading_path;
            % Updating the loading_path
            if ~isequal(selected_path,0)
               current_path = selected_path;
               app.information.loading_path = current_path;
            end
            
            % Write to feedback area
            write(app,strcat("Source folder: ",current_path));
            
            % Save information and parameters
            save_gui(app);
            
            % Refresh the application
            refresh(app);
        end

        % Button pushed function: SelectSavingButton
        function SelectSavingButtonPushed(app, event)
            % Select a path
            selected_path = uigetdir;
            
            
            % Fetching variables:
            current_path = app.information.saving_path;
            % Updating the saving_path
            if ~isequal(selected_path,1)
               current_path = selected_path;
               app.information.saving_path = current_path;
            end
            
            % Write to feedback area
            write(app,strcat("Destination folder: ",current_path));
            
            % Save information and parameters
            save_gui(app);
            
            % Refresh the application
            refresh(app);
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            save_gui(app)
            app.LaunchButton.Enable = 0;
            original_color = app.LaunchButton.BackgroundColor;
            app.LaunchButton.BackgroundColor = [0.93,0.69,0.13];
            app.LaunchButton.Text = "Analyzing...";
            pause(2)
            crawl(app.information,app.parameters);
            %try
             %   pause(2)
              %  crawl(app.information,app.parameters);
            %catch error
             %   disp(error)
              %  disp('Something went wrong during the analysis, write an issue on Github!');
            %end
            app.LaunchButton.BackgroundColor = original_color;
            app.LaunchButton.Enable = 1;
            app.LaunchButton.Text = "Launch";
            %calculate_all_motifs(app.loading_path,type,threshold,app.motif_number,app.saving_path);
            disp('Motif analysis terminated');            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            save_gui(app);
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            clear(app);
            save_gui(app);
            refresh(app);
        end

        % Close request function: EEGmotifsUIFigure
        function EEGmotifsUIFigureCloseRequest(app, event)
            remove_paths(app);
            delete(app);
            
        end

        % Value changed function: TypeofGraphDropDown
        function TypeofGraphDropDownValueChanged(app, event)
            value = app.TypeofGraphDropDown.Value;
            if(strcmp(value,"Binary"))
                app.ThresholdEditField.Enable = 1;
                app.parameters.general.is_weighted = 0;
                app.parameters.general.is_binary = 1;
            elseif(strcmp(value,"Weighted"))
                app.ThresholdEditField.Enable = 0;
                app.parameters.general.is_weighted = 1;
                app.parameters.general.is_binary = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGmotifsUIFigure = uifigure;
            app.EEGmotifsUIFigure.Name = 'EEGmotifs';
            app.EEGmotifsUIFigure.CloseRequestFcn = createCallbackFcn(app, @EEGmotifsUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.EEGmotifsUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.SelectLoadingButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.SelectLoadingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLoadingButtonPushed, true);
            app.SelectLoadingButton.Text = 'Select';
            app.SelectSavingButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.SelectSavingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSavingButtonPushed, true);
            app.SelectSavingButton.Text = 'Select';
            app.SelectsourcefolderLabel = uilabel(app.EEGmotifsUIFigure);
            app.SelectsourcefolderLabel.Text = 'Select source folder';
            app.SelectdestinationfolderLabel = uilabel(app.EEGmotifsUIFigure);
            app.SelectdestinationfolderLabel.Text = 'Select destination folder';
            app.LaunchButton = uibutton(app.EEGmotifsUIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Enable = 'off';
            app.LaunchButton.Text = 'Launch';
            app.ThresholdEditFieldLabel = uilabel(app.EEGmotifsUIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.EEGmotifsUIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.Value = 0.1;
            app.AnalysisTechniqueDropDownLabel = uilabel(app.EEGmotifsUIFigure);
            app.AnalysisTechniqueDropDownLabel.Text = 'Analysis Technique';
            app.AnalysisTechniqueDropDown = uidropdown(app.EEGmotifsUIFigure);
            app.AnalysisTechniqueDropDown.Items = {'dPLI', 'NSTE'};
            app.AnalysisTechniqueDropDown.Value = 'dPLI';
            app.NumberofmotifsDropDownLabel = uilabel(app.EEGmotifsUIFigure);
            app.NumberofmotifsDropDownLabel.Text = 'Number of motifs';
            app.NumberMotifsDropDown = uidropdown(app.EEGmotifsUIFigure);
            app.NumberMotifsDropDown.Items = {'3', '4'};
            app.NumberMotifsDropDown.Value = '3';
            app.FeedbackArea = uitextarea(app.EEGmotifsUIFigure);
            app.FeedbackArea.Editable = 'off';
            app.FeedbackArea.Value = {'Feedback Area'};
            app.SourceLamp = uilamp(app.EEGmotifsUIFigure);
            app.DestinationLamp = uilamp(app.EEGmotifsUIFigure);
            app.TypeofGraphDropDownLabel = uilabel(app.EEGmotifsUIFigure);
            app.TypeofGraphDropDownLabel.Text = 'Type of Graph';
            app.TypeofGraphDropDown = uidropdown(app.EEGmotifsUIFigure);
            app.TypeofGraphDropDown.Items = {'Binary', 'Weighted'};
            app.TypeofGraphDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofGraphDropDownValueChanged, true);
            app.TypeofGraphDropDown.Value = 'Weighted';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEGmotifs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGmotifsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGmotifsUIFigure)
        end
    end
end